classdef modoGrafico_iiDAQ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        iiDAQModogrficoUIFigure      matlab.ui.Figure
        Image2                       matlab.ui.control.Image
        arduinoOn                    matlab.ui.control.Button
        arduinoOff                   matlab.ui.control.Button
        bitsSlider                   matlab.ui.control.Slider
        BitsEditFieldLabel           matlab.ui.control.Label
        bitsField                    matlab.ui.control.NumericEditField
        CiclodeTrabajoKnobLabel      matlab.ui.control.Label
        knobDutyCycle0               matlab.ui.control.Knob
        knobDutyCycle1               matlab.ui.control.Knob
        Label                        matlab.ui.control.Label
        fieldDutyCycle0              matlab.ui.control.NumericEditField
        EditField_2Label             matlab.ui.control.Label
        fieldDutyCycle1              matlab.ui.control.NumericEditField
        EditField_3Label             matlab.ui.control.Label
        fieldDutyCycle2              matlab.ui.control.NumericEditField
        knobDutyCycle2               matlab.ui.control.Knob
        enablePin0                   matlab.ui.control.CheckBox
        enablePin1                   matlab.ui.control.CheckBox
        enablePin2                   matlab.ui.control.CheckBox
        FormadeondaKnobLabel         matlab.ui.control.Label
        knobWaveForm                 matlab.ui.control.DiscreteKnob
        CanalSwitchLabel             matlab.ui.control.Label
        channelSwitch                matlab.ui.control.RockerSwitch
        FrecuenciaLabel              matlab.ui.control.Label
        fieldFrequencyWave           matlab.ui.control.NumericEditField
        FaseLabel                    matlab.ui.control.Label
        fieldPhaseWave               matlab.ui.control.NumericEditField
        HzLabel                      matlab.ui.control.Label
        gradesLabel                  matlab.ui.control.Label
        PuertoCOMDropDownLabel       matlab.ui.control.Label
        portCOMList                  matlab.ui.control.DropDown
        ADC0Diferencial12Label       matlab.ui.control.Label
        ADC1Diferencial34Label       matlab.ui.control.Label
        fieldVoltageADC0             matlab.ui.control.NumericEditField
        fieldVoltageADC1             matlab.ui.control.NumericEditField
        fieldVoltageADC2             matlab.ui.control.NumericEditField
        ADC2Label                    matlab.ui.control.Label
        ADC3Label                    matlab.ui.control.Label
        fieldVoltageADC3             matlab.ui.control.NumericEditField
        UnidadesDropDownLabel        matlab.ui.control.Label
        unitsADC                     matlab.ui.control.DropDown
        ModoLabel                    matlab.ui.control.Label
        modeADCSwitch                matlab.ui.control.RockerSwitch
        GananciaDropDownLabel        matlab.ui.control.Label
        gainDropDown                 matlab.ui.control.DropDown
        buttonPinA0                  matlab.ui.control.StateButton
        buttonPinA1                  matlab.ui.control.StateButton
        buttonPinA2                  matlab.ui.control.StateButton
        buttonPinA3                  matlab.ui.control.StateButton
        Image                        matlab.ui.control.Image
        UniversidadNacionalAutnomadeMxicoLabel  matlab.ui.control.Label
        iiDAQLabel                   matlab.ui.control.Label
        MduloDACConvertidorDigitalAnalgicoLabel  matlab.ui.control.Label
        MduloGeneradordesealesLabel  matlab.ui.control.Label
        SealesPWMLabel               matlab.ui.control.Label
        PinesDigitalesLabel          matlab.ui.control.Label
        P0SwitchLabel                matlab.ui.control.Label
        P0Switch                     matlab.ui.control.ToggleSwitch
        P1SwitchLabel                matlab.ui.control.Label
        P1Switch                     matlab.ui.control.ToggleSwitch
        P2SwitchLabel                matlab.ui.control.Label
        P2Switch                     matlab.ui.control.ToggleSwitch
        P3SwitchLabel                matlab.ui.control.Label
        P3Switch                     matlab.ui.control.ToggleSwitch
        modePinDigSwitch             matlab.ui.control.RockerSwitch
        Label_2                      matlab.ui.control.Label
        buttonPinA0_2                matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
        arrCh
        serialPortEnable
        stopArduino
    end
    
    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: iiDAQModogrficoUIFigure
        function iiDAQModogrficoUIFigureCloseRequest(app, event)
            if app.arduinoOff.Enable == 'on'
                f=warndlg('El dispositivo se encuentra conectado','Precaución');
            else
                delete(app)
            end
            
            
        end

        % Drop down opening function: portCOMList
        function portCOMListOpening(app, event)
            portReady = serialportlist("available");
            app.portCOMList.Items = portReady; 
            pause(1/100);
            app.serialPortEnable = convertCharsToStrings(app.portCOMList.Value); 
            
        end

        % Button pushed function: arduinoOn
        function arduinoConnectedButtonPushed(app, event)
            if app.serialPortEnable ~= ''
                clc
                serialPort = app.serialPortEnable;
                app.s = connectiiDAQ(serialPort);
                set(app.arduinoOff,"Enable", 'on');
                set(app.bitsField,"Enable", 'on');
                set(app.bitsSlider,"Enable", 'on');
                set(app.knobWaveForm,"Enable", 'on');
                set(app.enablePin0,"Enable", 'on');
                set(app.enablePin1,"Enable", 'on');
                set(app.enablePin2,"Enable", 'on');
                set(app.modeADCSwitch,"Enable", 'on');
                set(app.buttonPinA0,"Enable", 'on');
                set(app.buttonPinA1,"Enable", 'on');
                set(app.buttonPinA2,"Enable", 'on');
                set(app.buttonPinA3,"Enable", 'on');
                set(app.gainDropDown,"Enable", 'on');
                set(app.modePinDigSwitch,"Enable", 'on');
                set(app.P0Switch,"Enable", 'on');
                set(app.P1Switch,"Enable", 'on');
                set(app.P2Switch,"Enable", 'on');
                set(app.P3Switch,"Enable", 'on');
                set(app.arduinoOn,"Enable", 'off');
                pause (1)
                app.stopArduino=0;             
            else
                f=warndlg('Debes escoger el puerto COM disponible','Puerto COM');
            end
        end

        % Button pushed function: arduinoOff
        function arduinoDisconnectedButtonPushed(app, event)
            set(app.arduinoOff,"Enable", 'off')
            set(app.bitsField,"Enable", 'off');
            set(app.bitsSlider,"Enable", 'off');
            set(app.channelSwitch,"Enable", 'off');
            set(app.knobWaveForm,"Enable", 'off');
            set(app.fieldFrequencyWave,"Enable",'off');
            set(app.fieldPhaseWave,"Enable",'off');
            set(app.enablePin0,"Enable", 'off');
            set(app.enablePin1,"Enable", 'off');
            set(app.enablePin2,"Enable", 'off');
            set(app.knobDutyCycle0,"Enable", 'off');
            set(app.knobDutyCycle1,"Enable", 'off');
            set(app.knobDutyCycle2,"Enable", 'off');
            set(app.fieldDutyCycle0,"Enable", 'off');
            set(app.fieldDutyCycle1,"Enable", 'off');
            set(app.fieldDutyCycle2,"Enable", 'off');
            set(app.modeADCSwitch,"Enable", 'off');
            set(app.buttonPinA0,"Enable", 'off');
            set(app.buttonPinA1,"Enable", 'off');
            set(app.buttonPinA2,"Enable", 'off');
            set(app.buttonPinA3,"Enable", 'off');
            set(app.fieldVoltageADC0,"Enable", 'off');
            set(app.fieldVoltageADC1,"Enable", 'off');
            set(app.fieldVoltageADC2,"Enable", 'off');
            set(app.fieldVoltageADC3,"Enable", 'off');
            set(app.unitsADC,"Enable", 'off');
            set(app.gainDropDown,"Enable", 'off');
            set(app.modePinDigSwitch,"Enable", 'on');
            set(app.P0Switch,"Enable", 'off');
            set(app.P1Switch,"Enable", 'off');
            set(app.P2Switch,"Enable", 'off');
            set(app.P3Switch,"Enable", 'off');
            set(app.arduinoOn,"Enable", 'on')
            app.bitsField.Value = 0;
            app.bitsSlider.Value = 0;
            app.knobWaveForm.Value ='Off';
            app.channelSwitch.Value = 'Ch1';
            app.fieldFrequencyWave.Value = 0;
            app.fieldPhaseWave.Value = 0;
            app.enablePin0.Value = 0;
            app.enablePin1.Value = 0;
            app.enablePin2.Value = 0;
            app.knobDutyCycle0.Value = 0;
            app.knobDutyCycle1.Value = 0;
            app.knobDutyCycle2.Value = 0;
            app.fieldDutyCycle0.Value = 0;
            app.fieldDutyCycle1.Value = 0;
            app.fieldDutyCycle2.Value = 0;
            app.modeADCSwitch.Value = 'Single';
            app.gainDropDown.Value = '1';
            app.buttonPinA0.Value = 0;
            app.buttonPinA1.Value = 0;
            app.buttonPinA2.Value = 0;
            app.buttonPinA3.Value = 0;
            app.buttonPinA0.Text = 'On';
            app.buttonPinA1.Text = 'On';
            app.buttonPinA2.Text = 'On';
            app.buttonPinA3.Text = 'On';
            app.fieldVoltageADC0.Value = 0;
            app.fieldVoltageADC1.Value = 0;
            app.fieldVoltageADC2.Value = 0;
            app.fieldVoltageADC3.Value = 0;
            app.unitsADC.Value = 'V';
            app.modePinDigSwitch.Value='Output';
            app.P0Switch.Value = 'Off';
            app.P1Switch.Value = 'Off';
            app.P2Switch.Value = 'Off';
            app.P3Switch.Value = 'Off';
            disconnectiiDAQ(app.s);
            pause (1)
            app.stopArduino=1;
        end

        % Value changed function: bitsField
        function bitsFieldValueChanged(app, event)
            value = app.bitsField.Value;
            if value<0 || value>4095
                f=warndlg('Debes colocar un valor dentro del rango permitido','Valor fuera de rango');
            else
                configureDAC(app.s, value);
                set(app.bitsSlider,'Value',round(value));
            end
            
        end

        % Value changed function: bitsSlider
        function bitsSliderValueChanged(app, event)
            value = app.bitsSlider.Value;
            configureDAC(app.s, value);
            set(app.bitsField,'Value',round(value));
        end

        % Value changed function: enablePin0
        function enablePin0ValueChanged(app, event)
            value = app.enablePin0.Value;
            if value==0
                set(app.knobDutyCycle0,"Value", 0);
                set(app.fieldDutyCycle0,"Value", 0);
                set(app.knobDutyCycle0,"Enable", 'off');
                set(app.fieldDutyCycle0,"Enable", 'off');
            else
                set(app.knobDutyCycle0,"Enable", 'on');
                set(app.fieldDutyCycle0,"Enable", 'on');
            end
        end

        % Value changed function: enablePin1
        function enablePin1ValueChanged(app, event)
            value = app.enablePin1.Value;
            if value==0
                set(app.knobDutyCycle1,"Value", 0);
                set(app.fieldDutyCycle1,"Value", 0);
                set(app.knobDutyCycle1,"Enable", 'off');
                set(app.fieldDutyCycle1,"Enable", 'off');
            else
                set(app.knobDutyCycle1,"Enable", 'on');
                set(app.fieldDutyCycle1,"Enable", 'on');
            end
        end

        % Value changed function: enablePin2
        function enablePin2ValueChanged(app, event)
            value = app.enablePin2.Value;
            if value==0
                set(app.knobDutyCycle2,"Value", 0);
                set(app.fieldDutyCycle2,"Value", 0);
                set(app.knobDutyCycle2,"Enable", 'off');
                set(app.fieldDutyCycle2,"Enable", 'off');
            else
                set(app.knobDutyCycle2,"Enable", 'on');
                set(app.fieldDutyCycle2,"Enable", 'on');
            end
        end

        % Value changed function: fieldDutyCycle0
        function fieldDutyCycle0ValueChanged(app, event)
            value = app.fieldDutyCycle0.Value;
            if value<0 || value>100
                f=warndlg('Debes colocar un valor dentro del rango permitido','Valor fuera de rango');
            else
                pinEnable=0;
                configurePWM(app.s,pinEnable,value);
                set(app.knobDutyCycle0,'Value',round(value));
            end
        end

        % Value changed function: fieldDutyCycle1
        function fieldDutyCycle1ValueChanged(app, event)
            value = app.fieldDutyCycle1.Value;
            if value<0 || value>100
                f=warndlg('Debes colocar un valor dentro del rango permitido','Valor fuera de rango');
            else
                pinEnable=1;
                configurePWM(app.s,pinEnable,value);
                set(app.knobDutyCycle1,'Value',round(value));
            end
        end

        % Value changed function: fieldDutyCycle2
        function fieldDutyCycle2ValueChanged(app, event)
            value = app.fieldDutyCycle2.Value;
            if value<0 || value>100
                f=warndlg('Debes colocar un valor dentro del rango permitido','Valor fuera de rango');
            else
                pinEnable=2;
                configurePWM(app.s,pinEnable,value);
                set(app.knobDutyCycle2,'Value',round(value));
            end
        end

        % Value changed function: knobDutyCycle0
        function knobDutyCycle0ValueChanged(app, event)
            value = app.knobDutyCycle0.Value;
            pinEnable=0;
            configurePWM(app.s,pinEnable,value);
            set(app.fieldDutyCycle0,'Value',round(value));
        end

        % Value changed function: knobDutyCycle1
        function knobDutyCycle1ValueChanged(app, event)
            value = app.knobDutyCycle1.Value;
            pinEnable=1;
            configurePWM(app.s,pinEnable,value);
            set(app.fieldDutyCycle1,'Value',round(value));
        end

        % Value changed function: knobDutyCycle2
        function knobDutyCycle2ValueChanged(app, event)
            value = app.knobDutyCycle2.Value;
            pinEnable=2;
            configurePWM(app.s,pinEnable,value);
            set(app.fieldDutyCycle2,'Value',round(value));
        end

        % Value changed function: knobWaveForm
        function knobWaveFormValueChanged(app, event)
            value = app.knobWaveForm.Value;
            valueStr = convertCharsToStrings(value);
            if valueStr=="Off"
                set(app.channelSwitch,"Enable", 'off');
                set(app.fieldFrequencyWave,"Enable", 'off');
                set(app.fieldPhaseWave,"Enable", 'off');
                fprintf(app.s,':OSO;');
            else 
                set(app.channelSwitch,"Enable", 'on');
                set(app.fieldFrequencyWave,"Enable", 'on');
                set(app.fieldPhaseWave,"Enable", 'on');
            end
            if valueStr=="Sinusoidal"
                fprintf(app.s,':OSS;');
            end
            if valueStr=="Triangular"
                fprintf(app.s,':OST;');
            end
            if valueStr=="Cuadrada"
                fprintf(app.s,':OSQ;');
            end
        end

        % Value changed function: channelSwitch
        function channelSwitchValueChanged(app, event)
            value = app.channelSwitch.Value;
            valueStr = convertCharsToStrings(value);
            if valueStr=="Ch1"
                app.arrCh = 1;
            end
            if valueStr=="Ch2" 
                app.arrCh = 2;
            end
            
        end

        % Value changed function: fieldFrequencyWave
        function fieldFrequencyWaveValueChanged(app, event)
            value = (app.fieldFrequencyWave.Value);
            if value<0 || value>10000000
                f=warndlg('Debes colocar un valor dentro del rango permitido','Valor fuera de rango');
            else
                arrSG{1,1} = num2str(value);
                if app.arrCh == 1
                    fprintf(app.s, ':OF1;');
                    pause(1);
                    fprintf(app.s, strcat(':F1',cell2mat(arrSG(1)),';'));
                else
                    fprintf(app.s, ':OF2;');
                    pause(1);
                    fprintf(app.s, strcat(':F2',cell2mat(arrSG(1)),';'));
                end
            end
        end

        % Value changed function: fieldPhaseWave
        function fieldPhaseWaveValueChanged(app, event)
            value = (app.fieldPhaseWave.Value*1000);
            if value<0 || value>360
                f=warndlg('Debes colocar un valor dentro del rango permitido','Valor fuera de rango');
            else
                arrSG{1,1} = num2str(value);
                if app.arrCh == 1
                    fprintf(app.s, ':OP1;');
                    pause(1);
                    fprintf(app.s, strcat(':P1',cell2mat(arrSG(1)),';'));
                else
                    fprintf(app.s, ':OP2;');
                    pause(1);
                    fprintf(app.s, strcat(':P2',cell2mat(arrSG(1)),';'));
                end
            end
            
        end

        % Value changed function: modeADCSwitch
        function modeADCSwitchValueChanged(app, event)
            value = app.modeADCSwitch.Value;
            valueStr = convertCharsToStrings(value);
            if valueStr=="Single"
                fprintf(app.s, 'ADCMODE0');
                set(app.buttonPinA0,"Enable", 'on');
                set(app.buttonPinA1,"Enable", 'on');
                set(app.buttonPinA2,"Enable", 'on');
                set(app.buttonPinA3,"Enable", 'on');
                set(app.fieldVoltageADC0,"Enable", 'on');
                set(app.fieldVoltageADC1,"Enable", 'on');
                set(app.fieldVoltageADC2,"Enable", 'on');
                set(app.fieldVoltageADC3,"Enable", 'on');
            end
            if valueStr=="Diff" 
                fprintf(app.s, 'ADCMODE1');
                set(app.buttonPinA2,"Enable", 'off');
                set(app.buttonPinA3,"Enable", 'off');
                set(app.fieldVoltageADC2,"Enable", 'off');
                set(app.fieldVoltageADC3,"Enable", 'off');
            end
        end

        % Value changed function: gainDropDown
        function gainDropDownValueChanged(app, event)
            value = app.gainDropDown.Value;
            valueStr = convertCharsToStrings(value);
            if valueStr == "2/3"
                fprintf(app.s,'ADCGAIN0');
            end
            if valueStr == "1"
                fprintf(app.s,'ADCGAIN1');
            end
            if valueStr == "2"
                fprintf(app.s,'ADCGAIN2');
            end
            if valueStr == "4"
                fprintf(app.s,'ADCGAIN3');
            end
            if valueStr == "8"
                fprintf(app.s,'ADCGAIN4');
            end
            if valueStr == "16"
                fprintf(app.s,'ADCGAIN5');
            end
            
        end

        % Value changed function: buttonPinA0
        function buttonPinA0ValueChanged(app, event)
            value = app.buttonPinA0.Value;
            stop = 0;
            in=0;
                if value==0
                    set(app.fieldVoltageADC0,"Value", 0);
                    set(app.buttonPinA0,"Text", 'On');
                    set(app.fieldVoltageADC0,"Enable", 'off');   
                else      
                    set(app.fieldVoltageADC0,"Enable", 'on');
                    set(app.unitsADC,"Enable", 'on');
                    set(app.buttonPinA0,"Text", 'Off');
                    while stop~=1
                        in=in+1;
                        fprintf(app.s, 'READ0');
                        sen=fscanf(app.s, '%s');
                        readADC=str2num(sen);
                        readA0V(in)=((readADC(1)*33/10)/17000);
                        readA0mV(in)=((readADC(1)*3300)/17000);
                        if app.unitsADC.Value== "V"
                            set(app.fieldVoltageADC0,"Value", (readA0V(in)));
                        else
                            set(app.fieldVoltageADC0,"Value", (readA0mV(in)));
                        end
                        pause(100/1000);
                        if app.unitsADC.Value== "V"
                            plot(app.UIAxes,readA0V);
                            drawnow
                            ylabel(app.UIAxes,'Voltaje [V]');
                        else
                            plot(app.UIAxes,readA0mV);
                            drawnow
                            ylabel(app.UIAxes,'Voltaje [mV]');
                        end
                        if app.buttonPinA0.Value==0
                            stop=1;
                        end
                    end
                    
                end
        end

        % Value changed function: buttonPinA1
        function buttonPinA1ValueChanged(app, event)
            value = app.buttonPinA1.Value;
            stop = 0;
            in=0;
                if value==0
                    set(app.fieldVoltageADC1,"Value", 0);
                    set(app.buttonPinA1,"Text", 'On');
                    set(app.fieldVoltageADC1,"Enable", 'off');     
                else      
                    set(app.fieldVoltageADC1,"Enable", 'on');
                    set(app.unitsADC,"Enable", 'on');
                    set(app.buttonPinA1,"Text", 'Off');
                    while stop~=1
                        in=in+1;
                        fprintf(app.s, 'READ1\n');
                        sen=fscanf(app.s, '%s');
                        readADC=str2num(sen);
                        readA1V(in)=((readADC(1)*33/10)/17000);
                        readA1mV(in)=((readADC(1)*3300)/17000);
                        if app.unitsADC.Value== "V"
                            set(app.fieldVoltageADC1,"Value", (readA1V(in)));
                        else
                            set(app.fieldVoltageADC1,"Value", (readA1mV(in)));
                        end
                        pause(100/1000);
                        if app.unitsADC.Value== "V"
                            plot(app.UIAxes,readA1V);
                            drawnow
                            ylabel(app.UIAxes,'Voltaje [V]');
                        else
                            plot(app.UIAxes,readA1mV);
                            drawnow
                            ylabel(app.UIAxes,'Voltaje [mV]');
                        end
                        if app.buttonPinA1.Value==0
                            stop=1;
                        end
                    end
                    
                end
        end

        % Value changed function: buttonPinA2
        function buttonPinA2ValueChanged(app, event)
            value = app.buttonPinA2.Value;
            stop = 0;
            in=0;
                if value==0
                    set(app.fieldVoltageADC2,"Value", 0);
                    set(app.buttonPinA2,"Text", 'On');
                    set(app.fieldVoltageADC2,"Enable", 'off');
                else      
                    set(app.fieldVoltageADC2,"Enable", 'on');
                    set(app.unitsADC,"Enable", 'on');
                    set(app.buttonPinA2,"Text", 'Off');
                    while stop~=1
                        in=in+1;
                        fprintf(app.s, 'READ2');
                        sen=fscanf(app.s, '%s');
                        readADC=str2num(sen);
                        readA2V(in)=((readADC(1)*33/10)/17000);
                        readA2mV(in)=((readADC(1)*3300)/17000);
                        if app.unitsADC.Value== "V"
                            set(app.fieldVoltageADC2,"Value", (readA2V(in)));
                        else
                            set(app.fieldVoltageADC2,"Value", (readA2mV(in)));
                        end
                        pause(100/1000);
                        if app.unitsADC.Value== "V"
                            plot(app.UIAxes,readA2V);
                            drawnow
                            ylabel(app.UIAxes,'Voltaje [V]');
                        else
                            plot(app.UIAxes,readA2mV);
                            drawnow
                            ylabel(app.UIAxes,'Voltaje [mV]');
                        end
                        if app.buttonPinA2.Value==0
                            stop=1;
                        end
                    end
                    
                end
        end

        % Value changed function: buttonPinA3
        function buttonPinA3ValueChanged(app, event)
            value = app.buttonPinA3.Value;
            stop = 0;
            in=0;
                if value==0
                    set(app.fieldVoltageADC3,"Value", 0);
                    set(app.buttonPinA3,"Text", 'On');
                    set(app.fieldVoltageADC3,"Enable", 'off');  
                else      
                    set(app.fieldVoltageADC3,"Enable", 'on');
                    set(app.unitsADC,"Enable", 'on');
                    set(app.buttonPinA3,"Text", 'Off');
                    while stop~=1
                        in=in+1;
                        fprintf(app.s, 'READ3');
                        sen=fscanf(app.s, '%s');
                        readADC=str2num(sen);
                        readA3V(in)=((readADC(1)*33/10)/17000);
                        readA3mV(in)=((readADC(1)*3300)/17000);
                        if app.unitsADC.Value== "V"
                            set(app.fieldVoltageADC3,"Value", (readA3V(in)));
                        else
                            set(app.fieldVoltageADC3,"Value", (readA3mV(in)));
                        end
                        pause(100/1000);
                        if app.unitsADC.Value== "V"
                            plot(app.UIAxes,readA3V);
                            drawnow
                            ylabel(app.UIAxes,'Voltaje [V]');
                        else
                            plot(app.UIAxes,readA3mV); 
                            drawnow
                            ylabel(app.UIAxes,'Voltaje [mV]');
                        end
                        
                        if app.buttonPinA3.Value==0
                            stop=1;
                        end
                    end
                    
                end
            
        end

        % Value changed function: modePinDigSwitch
        function modePinDigSwitchValueChanged(app, event)
            value = app.modePinDigSwitch.Value;
            if value == "Output"
                set(app.P0Switch,"Enable", 'on');
                set(app.P1Switch,"Enable", 'on');
                set(app.P2Switch,"Enable", 'on');
                set(app.P3Switch,"Enable", 'on');
            else 
                set(app.P0Switch,"Enable", 'off');
                set(app.P1Switch,"Enable", 'off');
                set(app.P2Switch,"Enable", 'off');
                set(app.P3Switch,"Enable", 'off');
                app.P0Switch.Value = 'Off';
                app.P1Switch.Value = 'Off';
                app.P2Switch.Value = 'Off';
                app.P3Switch.Value = 'Off';
                fprintf(app.s, 'OPIN0L');
                fprintf(app.s, 'OPIN1L');
                fprintf(app.s, 'OPIN2L');
                fprintf(app.s, 'OPIN3L');
            end
        end

        % Value changed function: P0Switch
        function P0SwitchValueChanged(app, event)
            value = app.P0Switch.Value;
            digitalPin=0;
            if value == "On"
                configureDigitalPin(app.s,digitalPin,1,1);
            end
            if value == "Off"
                configureDigitalPin(app.s,digitalPin,0,1);
            end
        end

        % Value changed function: P1Switch
        function P1SwitchValueChanged(app, event)
            value = app.P1Switch.Value;
            digitalPin=1;
            if value == "On"
                configureDigitalPin(app.s,digitalPin,1,1);
            end
            if value == "Off"
                configureDigitalPin(app.s,digitalPin,0,1);
            end
        end

        % Value changed function: P2Switch
        function P2SwitchValueChanged(app, event)
            value = app.P2Switch.Value;
            digitalPin=2;
            if value == "On"
                configureDigitalPin(app.s,digitalPin,1,1);
            end
            if value == "Off"
                configureDigitalPin(app.s,digitalPin,0,1);
            end
        end

        % Value changed function: P3Switch
        function P3SwitchValueChanged(app, event)
            value = app.P3Switch.Value;
            digitalPin=3;
            if value == "On"
                configureDigitalPin(app.s,digitalPin,1,1);
            end
            if value == "Off"
                configureDigitalPin(app.s,digitalPin,0,1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.iiDAQModogrficoUIFigure = uifigure('Visible', 'off');
            app.iiDAQModogrficoUIFigure.Name = 'iiDAQ - Modo gráfico';
            app.iiDAQModogrficoUIFigure.Icon = 'icono.png';
            app.iiDAQModogrficoUIFigure.CloseRequestFcn = createCallbackFcn(app, @iiDAQModogrficoUIFigureCloseRequest, true);
            app.Image2 = uiimage(app.iiDAQModogrficoUIFigure);
            app.Image2.ImageSource = 'IIUNAM.jpg';
            app.arduinoOn = uibutton(app.iiDAQModogrficoUIFigure, 'push');
            app.arduinoOn.ButtonPushedFcn = createCallbackFcn(app, @arduinoConnectedButtonPushed, true);
            app.arduinoOn.Text = 'Conexión iiDAQ';
            app.arduinoOff = uibutton(app.iiDAQModogrficoUIFigure, 'push');
            app.arduinoOff.ButtonPushedFcn = createCallbackFcn(app, @arduinoDisconnectedButtonPushed, true);
            app.arduinoOff.Enable = 'off';
            app.arduinoOff.Text = 'Desconexión iiDAQ';
            app.bitsSlider = uislider(app.iiDAQModogrficoUIFigure);
            app.bitsSlider.Limits = [0 4095];
            app.bitsSlider.MajorTicks = [0 512 1024 2048 4095];
            app.bitsSlider.ValueChangedFcn = createCallbackFcn(app, @bitsSliderValueChanged, true);
            app.bitsSlider.Enable = 'off';
            app.BitsEditFieldLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.BitsEditFieldLabel.Text = 'Bits';
            app.bitsField = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.bitsField.ValueChangedFcn = createCallbackFcn(app, @bitsFieldValueChanged, true);
            app.bitsField.Enable = 'off';
            app.CiclodeTrabajoKnobLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.CiclodeTrabajoKnobLabel.Text = 'Ciclo de Trabajo (%)';
            app.knobDutyCycle0 = uiknob(app.iiDAQModogrficoUIFigure, 'continuous');
            app.knobDutyCycle0.ValueChangedFcn = createCallbackFcn(app, @knobDutyCycle0ValueChanged, true);
            app.knobDutyCycle0.Enable = 'off';
            app.knobDutyCycle1 = uiknob(app.iiDAQModogrficoUIFigure, 'continuous');
            app.knobDutyCycle1.ValueChangedFcn = createCallbackFcn(app, @knobDutyCycle1ValueChanged, true);
            app.knobDutyCycle1.Enable = 'off';
            app.Label = uilabel(app.iiDAQModogrficoUIFigure);
            app.Label.Text = '%';
            app.fieldDutyCycle0 = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.fieldDutyCycle0.ValueChangedFcn = createCallbackFcn(app, @fieldDutyCycle0ValueChanged, true);
            app.fieldDutyCycle0.Enable = 'off';
            app.EditField_2Label = uilabel(app.iiDAQModogrficoUIFigure);
            app.EditField_2Label.Text = '%';
            app.fieldDutyCycle1 = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.fieldDutyCycle1.ValueChangedFcn = createCallbackFcn(app, @fieldDutyCycle1ValueChanged, true);
            app.fieldDutyCycle1.Enable = 'off';
            app.EditField_3Label = uilabel(app.iiDAQModogrficoUIFigure);
            app.EditField_3Label.Text = '%';
            app.fieldDutyCycle2 = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.fieldDutyCycle2.ValueChangedFcn = createCallbackFcn(app, @fieldDutyCycle2ValueChanged, true);
            app.fieldDutyCycle2.Enable = 'off';
            app.knobDutyCycle2 = uiknob(app.iiDAQModogrficoUIFigure, 'continuous');
            app.knobDutyCycle2.ValueChangedFcn = createCallbackFcn(app, @knobDutyCycle2ValueChanged, true);
            app.knobDutyCycle2.Enable = 'off';
            app.enablePin0 = uicheckbox(app.iiDAQModogrficoUIFigure);
            app.enablePin0.ValueChangedFcn = createCallbackFcn(app, @enablePin0ValueChanged, true);
            app.enablePin0.Enable = 'off';
            app.enablePin0.Text = '0';
            app.enablePin1 = uicheckbox(app.iiDAQModogrficoUIFigure);
            app.enablePin1.ValueChangedFcn = createCallbackFcn(app, @enablePin1ValueChanged, true);
            app.enablePin1.Enable = 'off';
            app.enablePin1.Text = '1';
            app.enablePin2 = uicheckbox(app.iiDAQModogrficoUIFigure);
            app.enablePin2.ValueChangedFcn = createCallbackFcn(app, @enablePin2ValueChanged, true);
            app.enablePin2.Enable = 'off';
            app.enablePin2.Text = '2';
            app.FormadeondaKnobLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.FormadeondaKnobLabel.Text = 'Forma de onda';
            app.knobWaveForm = uiknob(app.iiDAQModogrficoUIFigure, 'discrete');
            app.knobWaveForm.Items = {'Off', 'Sinusoidal', 'Triangular', 'Cuadrada'};
            app.knobWaveForm.ValueChangedFcn = createCallbackFcn(app, @knobWaveFormValueChanged, true);
            app.knobWaveForm.Enable = 'off';
            app.CanalSwitchLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.CanalSwitchLabel.Text = 'Canal';
            app.channelSwitch = uiswitch(app.iiDAQModogrficoUIFigure, 'rocker');
            app.channelSwitch.Items = {'Ch2', 'Ch1'};
            app.channelSwitch.ValueChangedFcn = createCallbackFcn(app, @channelSwitchValueChanged, true);
            app.channelSwitch.Enable = 'off';
            app.channelSwitch.Value = 'Ch1';
            app.FrecuenciaLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.fieldFrequencyWave = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.fieldFrequencyWave.ValueChangedFcn = createCallbackFcn(app, @fieldFrequencyWaveValueChanged, true);
            app.fieldFrequencyWave.Enable = 'off';
            app.FaseLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.FaseLabel.Text = 'Fase';
            app.fieldPhaseWave = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.fieldPhaseWave.ValueChangedFcn = createCallbackFcn(app, @fieldPhaseWaveValueChanged, true);
            app.fieldPhaseWave.Enable = 'off';
            app.HzLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.HzLabel.Text = '[Hz]';
            app.gradesLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.gradesLabel.Text = '[°]';
            app.PuertoCOMDropDownLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.PuertoCOMDropDownLabel.Text = 'Puerto COM';
            app.portCOMList = uidropdown(app.iiDAQModogrficoUIFigure);
            app.portCOMList.Items = {''};
            app.portCOMList.DropDownOpeningFcn = createCallbackFcn(app, @portCOMListOpening, true);
            app.portCOMList.Value = '';
            app.ADC0Diferencial12Label = uilabel(app.iiDAQModogrficoUIFigure);
            app.ADC0Diferencial12Label.Text = 'ADC0 / Diferencial 1-2';
            app.ADC1Diferencial34Label = uilabel(app.iiDAQModogrficoUIFigure);
            app.ADC1Diferencial34Label.Text = 'ADC1 / Diferencial 3-4';
            app.fieldVoltageADC0 = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.fieldVoltageADC0.Editable = 'off';
            app.fieldVoltageADC0.Enable = 'off';
            app.fieldVoltageADC1 = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.fieldVoltageADC1.Editable = 'off';
            app.fieldVoltageADC1.Enable = 'off';
            app.fieldVoltageADC2 = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.fieldVoltageADC2.Editable = 'off';
            app.fieldVoltageADC2.Enable = 'off';
            app.ADC2Label = uilabel(app.iiDAQModogrficoUIFigure);
            app.ADC2Label.Text = 'ADC2';
            app.ADC3Label = uilabel(app.iiDAQModogrficoUIFigure);
            app.ADC3Label.Text = 'ADC3 ';
            app.fieldVoltageADC3 = uieditfield(app.iiDAQModogrficoUIFigure, 'numeric');
            app.fieldVoltageADC3.Editable = 'off';
            app.fieldVoltageADC3.Enable = 'off';
            app.UnidadesDropDownLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.UnidadesDropDownLabel.Text = 'Unidades';
            app.unitsADC = uidropdown(app.iiDAQModogrficoUIFigure);
            app.unitsADC.Items = {'mV', 'V'};
            app.unitsADC.Enable = 'off';
            app.unitsADC.Value = 'V';
            app.ModoLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.ModoLabel.Text = 'Modo';
            app.modeADCSwitch = uiswitch(app.iiDAQModogrficoUIFigure, 'rocker');
            app.modeADCSwitch.Items = {'Diff', 'Single'};
            app.modeADCSwitch.ValueChangedFcn = createCallbackFcn(app, @modeADCSwitchValueChanged, true);
            app.modeADCSwitch.Enable = 'off';
            app.modeADCSwitch.Value = 'Single';
            app.GananciaDropDownLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.GananciaDropDownLabel.Text = 'Ganancia';
            app.gainDropDown = uidropdown(app.iiDAQModogrficoUIFigure);
            app.gainDropDown.Items = {'2/3', '1', '2', '4', '8', '16'};
            app.gainDropDown.ValueChangedFcn = createCallbackFcn(app, @gainDropDownValueChanged, true);
            app.gainDropDown.Enable = 'off';
            app.gainDropDown.Value = '1';
            app.buttonPinA0 = uibutton(app.iiDAQModogrficoUIFigure, 'state');
            app.buttonPinA0.ValueChangedFcn = createCallbackFcn(app, @buttonPinA0ValueChanged, true);
            app.buttonPinA0.Enable = 'off';
            app.buttonPinA0.Text = 'On';
            app.buttonPinA1 = uibutton(app.iiDAQModogrficoUIFigure, 'state');
            app.buttonPinA1.ValueChangedFcn = createCallbackFcn(app, @buttonPinA1ValueChanged, true);
            app.buttonPinA1.Enable = 'off';
            app.buttonPinA1.Text = 'On';
            app.buttonPinA2 = uibutton(app.iiDAQModogrficoUIFigure, 'state');
            app.buttonPinA2.ValueChangedFcn = createCallbackFcn(app, @buttonPinA2ValueChanged, true);
            app.buttonPinA2.Enable = 'off';
            app.buttonPinA2.Text = 'On';
            app.buttonPinA3 = uibutton(app.iiDAQModogrficoUIFigure, 'state');
            app.buttonPinA3.ValueChangedFcn = createCallbackFcn(app, @buttonPinA3ValueChanged, true);
            app.buttonPinA3.Enable = 'off';
            app.buttonPinA3.Text = 'On';
            app.Image = uiimage(app.iiDAQModogrficoUIFigure);
            app.Image.ImageSource = 'UNAM-Blanco.svg';
            app.UniversidadNacionalAutnomadeMxicoLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.UniversidadNacionalAutnomadeMxicoLabel.Text = 'Universidad Nacional Autónoma de México';
            app.iiDAQLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.iiDAQLabel.Text = 'iiDAQ';
            app.MduloDACConvertidorDigitalAnalgicoLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.MduloDACConvertidorDigitalAnalgicoLabel.Text = 'Módulo DAC (Convertidor Digital-Analógico)';
            app.MduloGeneradordesealesLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.MduloGeneradordesealesLabel.Text = 'Módulo Generador de señales';
            app.SealesPWMLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.SealesPWMLabel.Text = 'Señales PWM';
            app.PinesDigitalesLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.PinesDigitalesLabel.Text = 'Pines Digitales';
            app.P0SwitchLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.P0SwitchLabel.Text = 'P0';
            app.P0Switch = uiswitch(app.iiDAQModogrficoUIFigure, 'toggle');
            app.P0Switch.ValueChangedFcn = createCallbackFcn(app, @P0SwitchValueChanged, true);
            app.P0Switch.Enable = 'off';
            app.P1SwitchLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.P1SwitchLabel.Text = 'P1';
            app.P1Switch = uiswitch(app.iiDAQModogrficoUIFigure, 'toggle');
            app.P1Switch.ValueChangedFcn = createCallbackFcn(app, @P1SwitchValueChanged, true);
            app.P1Switch.Enable = 'off';
            app.P2SwitchLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.P2SwitchLabel.Text = 'P2';
            app.P2Switch = uiswitch(app.iiDAQModogrficoUIFigure, 'toggle');
            app.P2Switch.ValueChangedFcn = createCallbackFcn(app, @P2SwitchValueChanged, true);
            app.P2Switch.Enable = 'off';
            app.P3SwitchLabel = uilabel(app.iiDAQModogrficoUIFigure);
            app.P3SwitchLabel.Text = 'P3';
            app.P3Switch = uiswitch(app.iiDAQModogrficoUIFigure, 'toggle');
            app.P3Switch.ValueChangedFcn = createCallbackFcn(app, @P3SwitchValueChanged, true);
            app.P3Switch.Enable = 'off';
            app.modePinDigSwitch = uiswitch(app.iiDAQModogrficoUIFigure, 'rocker');
            app.modePinDigSwitch.Items = {'Output', 'Input'};
            app.modePinDigSwitch.ValueChangedFcn = createCallbackFcn(app, @modePinDigSwitchValueChanged, true);
            app.modePinDigSwitch.Enable = 'off';
            app.modePinDigSwitch.Value = 'Output';
            app.Label_2 = uilabel(app.iiDAQModogrficoUIFigure);
            app.Label_2.Text = 'Trabajo realizado con el apoyo del Programa UNAM-DGAPA-PAPIME PE101222';
            app.buttonPinA0_2 = uibutton(app.iiDAQModogrficoUIFigure, 'state');
            app.buttonPinA0_2.Enable = 'off';
            app.buttonPinA0_2.Text = 'Registro';
            app.UIAxes = uiaxes(app.iiDAQModogrficoUIFigure);
            title(app.UIAxes, {'Módulo ADC (Convertidor Analógico-Digital)'; ''})
            xlabel(app.UIAxes, 'Muestras')
            ylabel(app.UIAxes, 'Voltaje [V]')
            % Show the figure after all components are created
            app.iiDAQModogrficoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modoGrafico_iiDAQ

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.iiDAQModogrficoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.iiDAQModogrficoUIFigure)
        end
    end
end